classdef RLC_Analyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        button_calculate     matlab.ui.control.Button
        Label_3              matlab.ui.control.Label
        Label_2              matlab.ui.control.Label
        Label                matlab.ui.control.Label
        PhaseAngleLabel      matlab.ui.control.Label
        PeakCurrentILabel    matlab.ui.control.Label
        ImpedanceZLabel      matlab.ui.control.Label
        EditfEditField       matlab.ui.control.EditField
        EditfEditFieldLabel  matlab.ui.control.Label
        EditVEditField       matlab.ui.control.EditField
        EditVEditFieldLabel  matlab.ui.control.Label
        EditCEditField       matlab.ui.control.EditField
        EditCEditFieldLabel  matlab.ui.control.Label
        EditLEditField       matlab.ui.control.EditField
        EditLEditFieldLabel  matlab.ui.control.Label
        EditREditField       matlab.ui.control.EditField
        EditREditFieldLabel  matlab.ui.control.Label
        FrequencyfLabel      matlab.ui.control.Label
        PeakVoltageVLabel    matlab.ui.control.Label
        CapacitanceCLabel    matlab.ui.control.Label
        InductanceLLabel     matlab.ui.control.Label
        ResistanceRLabel     matlab.ui.control.Label
        axes1                matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: button_calculate
        function button_calculateButtonPushed(app, event)
            % --- 1. Get User Input and Convert from Text to Number ---
            R = str2double(app.EditREditField.Value);
            L = str2double(app.EditLEditField.Value);
            C = str2double(app.EditCEditField.Value);
            V_peak = str2double(app.EditVEditField.Value);
            f = str2double(app.EditfEditField.Value);
            
            % Basic error check for non-numeric input
            if isnan(R) || isnan(L) || isnan(C) || isnan(V_peak) || isnan(f)
                uialert(app.UIFigure, 'Please enter valid numeric values in all fields.', 'Input Error');
                return;
            end

            % --- 2. Perform Circuit Calculations ---
            w = 2 * pi * f;             % Angular frequency (rad/s)
            X_L = w * L;                % Inductive Reactance (Ohms)
            X_C = 1 / (w * C);          % Capacitive Reactance (Ohms)
            Z = sqrt(R^2 + (X_L - X_C)^2); % Total Impedance
            I_peak = V_peak / Z;        % Peak Current
            phi = atan2(X_L - X_C, R);   % Phase angle in radians
            phi_deg = phi * (180/pi);    % Convert to degrees

            % --- 3. Display the Results in the GUI Labels ---
            app.Label.Text = num2str(Z, '%.2f');       % Impedance Z
            app.Label_2.Text = num2str(I_peak, '%.2f'); % Peak Current I
            app.Label_3.Text = num2str(phi_deg, '%.2f'); % Phase Angle

            % --- 4. Plot the Waveforms ---
            t = linspace(0, 2/f, 1000); % Time vector for two cycles
            v_t = V_peak * sin(w * t);
            i_t = I_peak * sin(w * t - phi);

            % Plot on your designated axes, which is named 'axes1'
            plot(app.axes1, t, v_t, 'b-', 'LineWidth', 1.5);
            hold(app.axes1, 'on');
            plot(app.axes1, t, i_t, 'r--', 'LineWidth', 1.5);
            hold(app.axes1, 'off');

            % --- 5. Format the Plot ---
            title(app.axes1, 'Voltage and Current Waveforms');
            xlabel(app.axes1, 'Time (s)');
            ylabel(app.axes1, 'Amplitude');
            legend(app.axes1, 'Voltage (V)', 'Current (A)');
            grid(app.axes1, 'on');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.axes1 = uiaxes(app.UIFigure);
            title(app.axes1, 'Title')
            xlabel(app.axes1, 'X')
            ylabel(app.axes1, 'Y')
            zlabel(app.axes1, 'Z')
            app.ResistanceRLabel = uilabel(app.UIFigure);
            app.ResistanceRLabel.Text = 'Resistance (R)';
            app.InductanceLLabel = uilabel(app.UIFigure);
            app.InductanceLLabel.Text = 'Inductance (L)';
            app.CapacitanceCLabel = uilabel(app.UIFigure);
            app.CapacitanceCLabel.Text = 'Capacitance (C)';
            app.PeakVoltageVLabel = uilabel(app.UIFigure);
            app.PeakVoltageVLabel.Text = 'Peak Voltage (V)';
            app.FrequencyfLabel = uilabel(app.UIFigure);
            app.FrequencyfLabel.Text = 'Frequency (f)';
            app.EditREditFieldLabel = uilabel(app.UIFigure);
            app.EditREditFieldLabel.Text = 'Edit R';
            app.EditREditField = uieditfield(app.UIFigure, 'text');
            app.EditLEditFieldLabel = uilabel(app.UIFigure);
            app.EditLEditFieldLabel.Text = 'Edit L';
            app.EditLEditField = uieditfield(app.UIFigure, 'text');
            app.EditCEditFieldLabel = uilabel(app.UIFigure);
            app.EditCEditFieldLabel.Text = 'Edit C';
            app.EditCEditField = uieditfield(app.UIFigure, 'text');
            app.EditVEditFieldLabel = uilabel(app.UIFigure);
            app.EditVEditFieldLabel.Text = 'Edit V';
            app.EditVEditField = uieditfield(app.UIFigure, 'text');
            app.EditfEditFieldLabel = uilabel(app.UIFigure);
            app.EditfEditFieldLabel.Text = 'Edit f';
            app.EditfEditField = uieditfield(app.UIFigure, 'text');
            app.ImpedanceZLabel = uilabel(app.UIFigure);
            app.ImpedanceZLabel.Text = 'Impedance (Z)';
            app.PeakCurrentILabel = uilabel(app.UIFigure);
            app.PeakCurrentILabel.Text = 'Peak Current (I)';
            app.PhaseAngleLabel = uilabel(app.UIFigure);
            app.PhaseAngleLabel.Text = 'Phase Angle (φ)';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '0';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '0';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '0';
            app.button_calculate = uibutton(app.UIFigure, 'push');
            app.button_calculate.ButtonPushedFcn = createCallbackFcn(app, @button_calculateButtonPushed, true);
            app.button_calculate.Tag = 'button_calculate';
            app.button_calculate.Text = 'Calculate & Plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RLC_Analyzer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end